classdef tiemnghiem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NghimTab                      matlab.ui.container.Tab
        gx_2                          matlab.ui.control.Label
        ptgx                          matlab.ui.control.EditField
        gx                            matlab.ui.control.Label
        fab                           matlab.ui.control.Label
        SolveButton                   matlab.ui.control.Button
        lap                           matlab.ui.control.NumericEditField
        NumberofiterationsLabel       matlab.ui.control.Label
        kq                            matlab.ui.control.NumericEditField
        ResultLabel                   matlab.ui.control.Label
        ChoosesolutionmethodDropDown  matlab.ui.control.DropDown
        ChoosesolutionmethodDropDownLabel  matlab.ui.control.Label
        ss                            matlab.ui.control.NumericEditField
        b                             matlab.ui.control.NumericEditField
        a                             matlab.ui.control.NumericEditField
        EntertheallowederrorLabel     matlab.ui.control.Label
        pt                            matlab.ui.control.EditField
        NhpphngtrnhLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChoosesolutionmethodDropDown
        function ChoosesolutionmethodDropDownValueChanged(app, event)

            switch app.ChoosesolutionmethodDropDown.Value
                case 'Chia đôi'
                    app.b.Visible = 'on';
                    app.gx.Visible ='off';
                    app.ptgx.Visible = 'off';
                    app.fab.Text = 'Nhập khoảng nghiệm phân ly [a b]: ';
                case 'Lặp'
                    app.fab.Text = 'Nhập giá trị khởi tạo: ';
                    app.b.Visible = 'off';
                    app.gx.Text = 'Nhập hàm lặp g(x): ';
                    app.gx.Visible ='on';
                    app.ptgx.Visible ='on';
                case 'Newton'
                    app.fab.Text = 'Nhập giá trị khởi tạo: ';
                    app.b.Visible = 'off';
                    app.gx.Text = 'Đạo hàm của f(x): ';
                    app.gx.Visible ='on';
                    app.ptgx.Visible ='on';
                    
            end
            
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            select =0;
            switch app.ChoosesolutionmethodDropDown.Value
                case 'Chia đôi'
                    select =1;
                case 'Lặp'
                    select =2;
                case 'Newton'
                    select =3;
            end
            fx = str2func(['@(x)' app.pt.Value]);
            saiso = app.ss.Value;
            if select ==1
                a = app.a.Value;
                b= app.b.Value;            
                [result, n] = chia_doi(fx, a, b, saiso);
                
            elseif select ==2
                x0 = app.a.Value;
                g =str2func(['@(x)' app.ptgx.Value]);
                [result, n] = lap(fx, g, x0, saiso);
            elseif select ==3
                x0 = app.a.Value;
                daoham = str2func(['@(x)' app.ptgx.Value]);
                [result, n] = newton(fx, daoham, x0, saiso);
            end
            app.kq.Value = result;
            app.lap.Value= n;
            fplot(app.UIAxes,fx, 'DisplayName', 'f(x)');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,result, fx(result), 'ro', 'DisplayName', 'Nghiệm gần đúng');
            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'f(x)');
            title(app.UIAxes,'Đồ thị của phương trình f(x)');
            legend(app.UIAxes,'show');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
            %close(1);
                    

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhLabel.Text = 'Enter f(x): ';
            app.pt = uieditfield(app.NghimTab, 'text');
            app.EntertheallowederrorLabel = uilabel(app.NghimTab);
            app.EntertheallowederrorLabel.Text = 'Enter the allowed error:';
            app.a = uieditfield(app.NghimTab, 'numeric');
            app.b = uieditfield(app.NghimTab, 'numeric');
            app.ss = uieditfield(app.NghimTab, 'numeric');
            app.ChoosesolutionmethodDropDownLabel = uilabel(app.NghimTab);
            app.ChoosesolutionmethodDropDownLabel.Text = 'Choose solution method';
            app.ChoosesolutionmethodDropDown = uidropdown(app.NghimTab);
            app.ChoosesolutionmethodDropDown.Items = {'...', 'Chia đôi', 'Lặp', 'Newton'};
            app.ChoosesolutionmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosesolutionmethodDropDownValueChanged, true);
            app.ChoosesolutionmethodDropDown.Value = '...';
            app.ResultLabel = uilabel(app.NghimTab);
            app.ResultLabel.Text = 'Result';
            app.kq = uieditfield(app.NghimTab, 'numeric');
            app.NumberofiterationsLabel = uilabel(app.NghimTab);
            app.NumberofiterationsLabel.Text = 'Number of iterations';
            app.lap = uieditfield(app.NghimTab, 'numeric');
            app.SolveButton = uibutton(app.NghimTab, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.fab = uilabel(app.NghimTab);
            app.fab.Text = 'Enter [a b]: ';
            app.gx = uilabel(app.NghimTab);
            app.gx.Text = 'Enter g(x): ';
            app.ptgx = uieditfield(app.NghimTab, 'text');
            app.gx_2 = uilabel(app.NghimTab);
            app.gx_2.Text = 'GIẢI PHƯƠNG TRÌNH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tiemnghiem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end